classdef BasicApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BasicStimAppLabel        matlab.ui.control.Label
        StimControlsPanel        matlab.ui.container.Panel
        CHDropDownLabel          matlab.ui.control.Label
        CHDropDown               matlab.ui.control.DropDown
        PWEditFieldLabel         matlab.ui.control.Label
        PWEditField              matlab.ui.control.NumericEditField
        usLabel                  matlab.ui.control.Label
        PAEditFieldLabel         matlab.ui.control.Label
        PAEditField              matlab.ui.control.NumericEditField
        mALabel                  matlab.ui.control.Label
        HZLabel                  matlab.ui.control.Label
        sLabel                   matlab.ui.control.Label
        StartStimButton          matlab.ui.control.Button
        StopStimButton           matlab.ui.control.Button
        FrequencyEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.NumericEditField
        DurationEditFieldLabel   matlab.ui.control.Label
        DurationEditField        matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        TargetObject = slrealtime('TargetPC1'); % Description
        TargetModel  = 'UECU_Speedgoat_threshold';
        TimerObject = timer( 'ExecutionMode', 'fixedRate', 'Period', 0.1, 'TasksToExecute', inf, 'BusyMode', 'drop' );
    end
    
    properties (Access = private)
        StimParams % Holds the stimulation Parameters for the next stim
        TrialParams% Holds the duration of the trial
        %ch_in=1; %This is the channel the application will stimulate on
    end
    
    methods (Access = private)
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%% TIMER FUNCTIONS
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function user_timer_start(app, obj, event)
            % This function can also be used to enable/disable any objects,
            % screens or panels that the user needs to, or doesn't need to
            % interact with during stimulation. 

            
            % Refresh scopes and start target simulation
            app.TargetObject.start('StopTime', inf , 'ReloadOnStop', true, 'AutoImportFileLog', false, 'ExportToBaseWorkspace', false);
        end
        
        function user_timer_update(app, obj, event)
            
            % If target is not running, stop the timer
            if ~app.TargetObject.isRunning
                stop(app.TimerObject);
                return;
            end
        end
        
        function user_timer_stop(app, obj, event)
            
            % Stop target and clear instrument data, checks that the target
            % is actually running
            if app.TargetObject.isRunning
                app.TargetObject.stop;
            end

        end
        
        function user_timer_error(app, obj, event)
            stop(app.TimerObject);
        end

    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%% TARGET INTERACTION FUNCTIONS %%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function target_load_model(app)
        % TARGET_LOAD_MODEL(app)
        % 
        % Loads the target model specified in the app onto the target
        % machine (Speedgoat). Waits for model to load onto target.
   
          fprintf('Loading %s to TargetPC1 ...\n', app.TargetModel);
          app.TargetObject.load(app.TargetModel); % load model onto target
          disp('Load onto target complete?')
          %waitfor(app.TargetObject.ModelStatus, 'State', 'LOADED'); % wait for model to load on target (this line possibly does not work) %JDW editing out the line that may not work
          fprintf('%s loaded to TargetPC1!\n', app.TargetModel)
        end
        
        function target_update_param(app, block_name, param_name, value)
        % TARGET_UPDATE_PARAM(app, block_name, param_name, value)
        % 
        % Sets parameter value in specified Simulink block for target
        % model (specified under app.TargetModel).
        
          block_path = [app.TargetModel '/' block_name]; % get path name of Simulink block
          curr_val = app.TargetObject.getparam(block_path, param_name); % get current value of block parameter
          value = cast([value, zeros(1,length(curr_val)-length(value))], class(curr_val)); % pad and cast new value to current value data type
          app.TargetObject.setparam(block_path, param_name, value); % set block parameter with new values
        end
        
        function flag = target_set_block_params(app)
        % TARGET_SET_BLOCK_PARAMS(app, block_params)
        % 
        % Sets parameters of target model based on the values contained in 
        % the block_params structure.
            
            % Initialize success flag
            flag = false;
        
            % If model is not already loaded onto target, load model onto target
            if ~strcmp(app.TargetObject.ModelStatus.State, 'LOADED')
                target_load_model(app);
            end
            ch_in=zeros(1,24);
            pw_in  = zeros(1,24);
            pa_in  = zeros(1,24);
            ipi_in = zeros(1,24);
            for i = 1:16
                %ch_in(i)  = app.StimParams(i).Active;
                ch_in(i)  = app.StimParams(i).ch_in;
                pw_in(i)  = app.StimParams(i).PW;
                pa_in(i)  = app.StimParams(i).PA;
                ipi_in(i) = app.StimParams(i).IPI;
            end
            
            %if length(unique(ipi_in(ipi_in ~= 0))) > 1
            %    errordlg('Multiple different IPIs currently not supported!', 'Stim Parameter Error');
            %    %ClearMemoryButtonPushed(app);
            %    return;
            %end
            
            %target_update_param(app, 'trial_delay_in', 'Value', app.TrialParams.Delay);
            target_update_param(app, 'trial_stop_in',  'Value', app.TrialParams.Duration);
            target_update_param(app,'Enable Beep',   'Value',1)
            
            target_update_param(app, 'snp_CH_in',  'Value', ch_in);
            target_update_param(app, 'snp_PW_in',  'Value', pw_in);
            target_update_param(app, 'snp_PA_in',  'Value', pa_in);
            target_update_param(app, 'snp_IPI_in', 'Value', max(ipi_in));
            
            % Set success flag to true
            flag = true;
        end
        
        function [time, data] = export_target_data(app)
        % [time, data] = EXPORT_TARGET_DATA(app)
        % 
        % Gets data stored on the target machine. Assumes that the target
        % machine only has 1 log. Assumes that the model only contains a
        % single FileLog block.
        
            % Get the FileLog object from the target
            tg_file_log = app.TargetObject.FileLog;
            
            % Check if any data is available on the target
            if ~tg_file_log.DataAvailable 
                time = [];
                data = [];
                warndlg('No data available on target!', 'Target Check');
                return;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Disable Components during the build process
            app.StimControlsPanel.Enable='off'
            
            
            % Build and load the model onto the target
            slbuild(app.TargetModel);
            disp('slbuild complete')
            target_load_model(app);
            disp('target load complete')
            
            % Set the timer callback functions
            % The timer serves as a easy handle to start and stop the
            % target model. 
            app.TimerObject.StartFcn = {@app.user_timer_start};
            app.TimerObject.TimerFcn = {@app.user_timer_update};
            app.TimerObject.StopFcn  = {@app.user_timer_stop};
            app.TimerObject.ErrorFcn = {@app.user_timer_error};
            disp('Timer function Built')% JDW addition on 03/22/2022
            
            %Enable Components Post Build
            app.StimControlsPanel.Enable='on'
            
        end

        % Button pushed function: StartStimButton
        function StartStimButtonPushed(app, event)
                        
            % Save delay and duration parameters
            app.TrialParams.Duration = app.DurationEditField.Value;
            ActiveChannel=str2double(app.CHDropDown.Value);%Pulls in the value of the channel selected to stimulate from
            app.TrialParams.ActiveChannel=ActiveChannel

                 for i = 1:16
                    
                    % If channel is selected for stim (check box is clicked)
                    if i==app.TrialParams.ActiveChannel
                        % Save stim parameters to StimParams
                        app.StimParams(i).ch_in=1;
                        app.StimParams(i).PW= app.PWEditField.Value;
                        app.StimParams(i).PA=app.PAEditField.Value;
                        app.StimParams(i).IPI=(1000/app.FrequencyEditField.Value); % Convert from Frequency to IPI in ms

                    % If channel is not selected for stim (check box is empty)
                    else
                        app.StimParams(i).ch_in=0;
                        app.StimParams(i).PW  = 0;
                        app.StimParams(i).PA  = 0;
                        app.StimParams(i).IPI = 0;
                    end
                 end     
            % Update parameters on target
            updated = target_set_block_params(app);
            
            % Start the model
            if updated 
                start(app.TimerObject); % model is started when timer is started
            end
        end

        % Button pushed function: StopStimButton
        function StopStimButtonPushed(app, event)
            stop(app.TimerObject);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BasicStimAppLabel = uilabel(app.UIFigure);
            app.BasicStimAppLabel.Text = {'Basic Stim App V1:                                                                                                                                              '; ' This is the first version of the stimulation app which allows for basic stimulation. This uses the model SimpleStim.mldatx. '};
            app.StimControlsPanel = uipanel(app.UIFigure);
            app.StimControlsPanel.Title = 'Stim Controls';
            app.CHDropDownLabel = uilabel(app.StimControlsPanel);
            app.CHDropDownLabel.Text = 'CH';
            app.CHDropDown = uidropdown(app.StimControlsPanel);
            app.CHDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.CHDropDown.Value = '1';
            app.PWEditFieldLabel = uilabel(app.StimControlsPanel);
            app.PWEditFieldLabel.Text = 'PW';
            app.PWEditField = uieditfield(app.StimControlsPanel, 'numeric');
            app.PWEditField.Limits = [0 250];
            app.PWEditField.Value = 220;
            app.usLabel = uilabel(app.StimControlsPanel);
            app.usLabel.Text = 'us';
            app.PAEditFieldLabel = uilabel(app.StimControlsPanel);
            app.PAEditFieldLabel.Text = 'PA';
            app.PAEditField = uieditfield(app.StimControlsPanel, 'numeric');
            app.PAEditField.Limits = [0 5];
            app.PAEditField.Value = 0.75;
            app.mALabel = uilabel(app.StimControlsPanel);
            app.mALabel.Text = 'mA';
            app.HZLabel = uilabel(app.StimControlsPanel);
            app.HZLabel.Text = 'HZ';
            app.sLabel = uilabel(app.StimControlsPanel);
            app.sLabel.Text = 's';
            app.StartStimButton = uibutton(app.StimControlsPanel, 'push');
            app.StartStimButton.ButtonPushedFcn = createCallbackFcn(app, @StartStimButtonPushed, true);
            app.StartStimButton.Text = 'Start Stim';
            app.StopStimButton = uibutton(app.StimControlsPanel, 'push');
            app.StopStimButton.ButtonPushedFcn = createCallbackFcn(app, @StopStimButtonPushed, true);
            app.StopStimButton.Text = 'Stop Stim';
            app.FrequencyEditFieldLabel = uilabel(app.StimControlsPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.StimControlsPanel, 'numeric');
            app.FrequencyEditField.Limits = [1 1000];
            app.FrequencyEditField.Value = 100;
            app.DurationEditFieldLabel = uilabel(app.StimControlsPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.StimControlsPanel, 'numeric');
            app.DurationEditField.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BasicApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end