classdef initialize_subject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        PleaseselectsubjectDropDownLabel  matlab.ui.control.Label
        SubjectDropDown  matlab.ui.control.DropDown
        ConfirmButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp;
        SubjectProps;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.MainApp = main_app;
            app.UIFigure.Position = [ ...
                app.MainApp.UIFigure.Position(1) + (app.MainApp.UIFigure.Position(3)/2), ...
                app.MainApp.UIFigure.Position(2) + (app.MainApp.UIFigure.Position(4)/2), ...
                app.UIFigure.Position(3), app.UIFigure.Position(4) ];

            app.SubjectProps.LE01.CuffNames = {'Sciatic', 'Tibial', 'Peroneal'};
            app.SubjectProps.LE01.AmputatedSide = 'left';
            
            app.SubjectProps.LE02.CuffNames = {'Sciatic', 'Tibial', 'IM Electrodes'};
            app.SubjectProps.LE02.AmputatedSide = 'right';
            
            app.SubjectProps.LE03.CuffNames = {'Sciatic', 'Proximal Tibial', 'Distal Tibial'};
            app.SubjectProps.LE03.AmputatedSide = 'right';
            
            app.SubjectProps.LE04.CuffNames = {'Distal Sciatic', 'Middle Sciatic', 'Proximal Sciatic'};
            app.SubjectProps.LE04.AmputatedSide = 'right';
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.MainApp.SelectedSubjectProps = app.SubjectProps.(app.SubjectDropDown.Value);
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                delete(app.MainApp);
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PleaseselectsubjectDropDownLabel = uilabel(app.UIFigure);
            app.PleaseselectsubjectDropDownLabel.Text = 'Please select subject:';
            app.SubjectDropDown = uidropdown(app.UIFigure);
            app.SubjectDropDown.Items = {'LE01', 'LE02', 'LE03', 'LE04'};
            app.SubjectDropDown.Value = 'LE01';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = initialize_subject(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end