classdef johntestapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        StimulateButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Duration % Duration of the stim
        Delay % Delay of the stim
        PW % Stim PW in us
        PA % stim amplitude in mA
        IPI % stim IPI in ms
        CH % stim channel

        enablebeep % Description
        TrialParams % Description
        TimerObject = timer( 'ExecutionMode', 'fixedRate', 'Period', 0.1, 'TasksToExecute', inf, 'BusyMode', 'drop' );

    end
    
    properties (Access = public)
        TargetObject = slrealtime('TargetPC1');
        TargetModel  = 'UECU_Speedgoat_threshold';%JDW edit to try and fix the system getting stuck
    end
    
    methods (Access = private)
        
 % TARGET_SET_BLOCK_PARAMS(app, block_params)
        % 
        % Sets parameters of target model based on the values contained in 
        % the block_params structure.
            
            % Initialize success flag
            function flag = target_set_block_params(app)
            flag = false;
        
            % If model is not already loaded onto target, load model onto target
            if ~strcmp(app.TargetObject.ModelStatus.State, 'LOADED')
                target_load_model(app);
            end
            
            ch_in=app.CH;
            pw_in=app.PW;
            pa_in=app.PA;
            ipi_in=app.IPI;
            app.TrialParams.Delay=app.Delay;
            app.TrialParams.Duration=app.Duration;
            
            target_update_param(app, 'trial_delay_in', 'Value', app.TrialParams.Delay);
            target_update_param(app, 'trial_stop_in',  'Value', app.TrialParams.Duration);
            target_update_param(app, 'Enable Beep',    'Value', app.enablebeep);
            
            target_update_param(app, 'snp_CH_in',  'Value', ch_in);
            target_update_param(app, 'snp_PW_in',  'Value', pw_in);
            target_update_param(app, 'snp_PA_in',  'Value', pa_in);
            target_update_param(app, 'snp_IPI_in', 'Value', max(ipi_in));
            
            % Set success flag to true
            flag = true;
            end
        
        function target_update_param(app, block_name, param_name, value)
        % TARGET_UPDATE_PARAM(app, block_name, param_name, value)
        % 
        % Sets parameter value in specified Simulink block for target
        % model (specified under app.TargetModel).
        
          block_path = [app.TargetModel '/' block_name]; % get path name of Simulink block
          curr_val = app.TargetObject.getparam(block_path, param_name); % get current value of block parameter
          value = cast([value, zeros(1,length(curr_val)-length(value))], class(curr_val)); % pad and cast new value to current value data type
          app.TargetObject.setparam(block_path, param_name, value); % set block parameter with new values
        end
        
        function target_load_model(app)
        % TARGET_LOAD_MODEL(app)
        % 
        % Loads the target model specified in the app onto the target
        % machine (Speedgoat). Waits for model to load onto target.
   
          fprintf('Loading %s to TargetPC1 ...\n', app.TargetModel);
          app.TargetObject.load(app.TargetModel); % load model onto target
          disp('Load onto target complete?')
          %waitfor(app.TargetObject.ModelStatus, 'State', 'LOADED'); % wait for model to load on target (this line possibly does not work) %JDW editing out the line that may not work
          fprintf('%s loaded to TargetPC1!\n', app.TargetModel)
        end
        
 function user_timer_start(app, obj, event)
            
            % Disable the drawing GUI
            %app.DrawingGUIButton.Value = false;
            %app.WacomApp.disable_draw;
            %app.WacomApp.clear_wacom;
            
            % Uncheck No Response Button
            %if app.NoResponseButton.Value
             %   app.NoResponseButton.Value = false;
             %   app.NoResponseButtonValueChanged;
            %end
            
            % Update block number counter
            %app.TrialEditField.Value = app.TrialParams.Trial;
            
            % Refresh scopes and start target simulation
            %app.TargetObject.start('StopTime', inf , 'ReloadOnStop', true, 'AutoImportFileLog', false, 'ExportToBaseWorkspace', false);
        end
        
        function user_timer_update(app, obj, event)
            
            % If target is not running, stop the timer
            if ~app.TargetObject.isRunning
                stop(app.TimerObject);
                return;
            end
        end
        
        function user_timer_stop(app, obj, event)
            
            % Stop target and clear instrument data
            if app.TargetObject.isRunning
                app.TargetObject.stop;
            end
            
            % Enable the drawing GUI
            %app.DrawingGUIButton.Value = true;
            %app.WacomApp.enable_draw;
            
            %app.CatchTrialButton.Value = false;
            
            %app.SummaryApp.add_trial(app.SaveData);
            
            % Extra button options to ensure proper experimentation
            %app.SaveButton.FontColor = 'r';
        end
        
        function user_timer_error(app, obj, event)
            stop(app.TimerObject);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            % Initially disable the app while model loads to target

            % Set the timer callback functions
            app.TimerObject.StartFcn = {@app.user_timer_start};
            app.TimerObject.TimerFcn = {@app.user_timer_update};
            app.TimerObject.StopFcn  = {@app.user_timer_stop};
            app.TimerObject.ErrorFcn = {@app.user_timer_error};
            disp('Timer function Built')% JDW addition on 03/22/2022
           
            
            % Build and load the model onto the target
            slbuild(app.TargetModel);
            disp('slbuild complete')
            target_load_model(app);
            disp('target load complete')
            

        end

        % Button pushed function: StimulateButton
        function StimulateButtonPushed(app, event)
                        
            % Save delay and duration parameters
            app.Duration=5;
            app.Delay=1;
            PWValue=150;
            PAValue=.5;
            EnableBeep=1;
            CHValue=1;
            
            app.PW=zeros(1,24);
            app.PA=zeros(1,24);
            app.CH=zeros(1,24);
            
            app.IPI=10; % frequency of 100 HZ

            for i=1:16
                if i==CHValue
                    app.PW(i)=PWValue;
                    app.PA(i)=PAValue;
                    app.CH(i)=1;
                end
                
            end
 
            
            % Update parameters on target
            updated = target_set_block_params(app);
            
            % Start the model
            if updated 
                start(app.TimerObject); % model is started when timer is started
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Basic Model. No Bells and Whistles. Sends the values doesn''t do anything else';
            app.StimulateButton = uibutton(app.UIFigure, 'push');
            app.StimulateButton.ButtonPushedFcn = createCallbackFcn(app, @StimulateButtonPushed, true);
            app.StimulateButton.Text = 'Stimulate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = johntestapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end