classdef n_trials_summary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        UITable     matlab.ui.control.Table
        TableLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        MaxLines = 35;
        NumLines = 0;
    end
    
    methods (Access = public)
        function add_trial(app, save_data)
            
            curr_num_lines = app.NumLines;
            num_lines_to_add = length(save_data);
            if curr_num_lines+num_lines_to_add > app.MaxLines
                num_lines_to_remove = curr_num_lines+num_lines_to_add-app.MaxLines;
                app.UITable.Data(1:(curr_num_lines-num_lines_to_remove),:) = ...
                    app.UITable.Data((num_lines_to_remove+1):curr_num_lines,:);
                curr_num_lines = curr_num_lines-num_lines_to_remove;
            end
            
            for i = 1 : num_lines_to_add
                data_to_add = sprintf('%s;%s;%s;%d;%.3f;%.3f;%.3f;%.3f;%s;%s', ...
                    save_data(i).Index, '', save_data(i).Cuff, save_data(i).CH, ...
                    save_data(i).PW, save_data(i).PA, save_data(i).IPI, ...
                    save_data(i).QD, save_data(i).Response.Comments, save_data(i).Response.Location);
                data_to_add = strsplit(data_to_add, ';', 'CollapseDelimiters', false);
                app.UITable.Data(curr_num_lines+i,:) = data_to_add;
            end
            
            app.NumLines = curr_num_lines+num_lines_to_add;
        end
        
        
        function nlines_data = get_last_n_lines(app, nLines)
            nlines_data = app.UITable.Data((app.NumLines-nLines+1):app.NumLines,:);
        end
        
        function set_last_n_lines(app, nlines_data)
            app.UITable.Data((app.NumLines-size(nlines_data,1)+1):app.NumLines,:) = nlines_data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.UITable.Data = cell(0,size(app.UITable.Data,2));
            app.UIFigure.Position = app.UIFigure.Position + [200, 200, 0, 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Index'; 'Saved'; 'Cuff'; 'CH'; 'PW'; 'PA'; 'IPI'; 'QD'; 'Comments'; 'Location'};
            app.UITable.RowName = {};
            app.TableLabel = uilabel(app.UIFigure);
            app.TableLabel.Text = 'Summary of Last 35 Trials';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = n_trials_summary(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end