classdef wacom_draw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        SubmitButton  matlab.ui.control.Button
        ClearButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Axes;
        CurrIndex = [];
        CurrLine = line([], []);
        DrawEnable;
        DrawnLines = line([], []);
        DrawnLineProps = {'Color', [1,1,0], 'LineWidth', 2.5, 'HitTest', 'off'};
        Image;
        MainApp;
        NumLines = 0;
        SaveLines;
    end
    
    methods (Access = private)
        
        function [x, y] = get_current_point_on_axes(app)
        % [x, y] = GET_CURRENT_POINT_ON_AXES(app)
        % 
        % Gets current point mouse is located on the axis on the app. 
        
            loc = get(app.Axes, 'CurrentPoint');
            x   = loc(1,1,1);
            y   = loc(1,2,1);
        end
        
        function pencil_start(app, varargin)
        % PENCIL_START(app)
        % 
        % Callback function executes when the "mouse" or pen is clicked on
        % the axis. Begins drawing the line.
        
            if app.DrawEnable % if drawing is enabled
                [x, y] = get_current_point_on_axes(app);
                app.CurrLine = line(app.Axes, x, y, app.DrawnLineProps{:}); % begin the line
                set(app.UIFigure, 'WindowButtonMotionFcn', {@app.pencil_continue}); % set motion callback
                set(app.UIFigure, 'WindowButtonUpFcn',     {@app.pencil_done});     % set button up callback
            else % if drawing is not enabled, do nothing
                set(app.UIFigure, 'WindowButtonMotionFcn', '');
                set(app.UIFigure, 'WindowButtonUpFcn',     '');                
            end
        end
        
        function pencil_continue(app, varargin)
        % PENCIL_CONTINUE(app)
        % 
        % Callback function executes when the "mouse" or pen is clicked and
        % moving on the axis. Continues drawing the line under the pen.
        
            [x, y] = get_current_point_on_axes(app);
            if isempty(app.CurrLine) % if line object is empty, create new line
                app.CurrLine = line(app.Axes, x, y, app.DrawnLineProps{:});
            else % if line object exists, continue drawing on line
                prev_x = app.CurrLine.XData;
                prev_y = app.CurrLine.YData;
                app.CurrLine.XData = [prev_x, x];
                app.CurrLine.YData = [prev_y, y];
            end
            
            drawnow limitrate
        end
        
        function pencil_done(app, varargin)
        % PENCIL_DONE(app)
        % 
        % Callback function executes when the "mouse" or pen is lifted.
        % This indicates that the current line is done. 
        
            if ~isempty(app.CurrLine) % if line exists
                app.NumLines = app.NumLines+1; % increment number of lines
                app.DrawnLines(app.NumLines) = app.CurrLine; % add current line to drawn lines
                app.SaveLines(app.NumLines).index = app.CurrIndex; % save current index of line
                app.SaveLines(app.NumLines).coord = [app.CurrLine.XData; app.CurrLine.YData]; % save coordinates of line
                app.CurrLine = line([], []); % reset CurrLine object
            end
            set(app.UIFigure, 'WindowButtonMotionFcn', '');
            set(app.UIFigure, 'WindowButtonUpFcn',     '');
        end
    end
    
    methods (Access = public)
        function enable_draw(app)
        % ENABLE_DRAW(app)
        % 
        % Enables drawing on the Wacom tablet.
        
            % Move the mouse to the Wacom tablet
            app.MainApp.MouseObject.mouseMove( ...
                app.UIFigure.Position(1)+(app.UIFigure.Position(3)/2), ...
                app.UIFigure.Position(2)+(app.UIFigure.Position(4)/2) );
            
            % Not sure what this does. Migrated from previous version.
            heapTotalMemory = java.lang.Runtime.getRuntime.totalMemory;
            heapFreeMemory = java.lang.Runtime.getRuntime.freeMemory;
            if(heapFreeMemory < (heapTotalMemory*0.01))
                java.lang.Runtime.getRuntime.gc;
            end
            
            % Grab the current relevant index
            if isempty(app.MainApp.SaveData)
                app.CurrIndex = app.MainApp.get_curr_index;
            else
                app.CurrIndex = app.MainApp.SaveData(1).Index;
            end
            
            % Enable drawing properties
            app.DrawEnable = true;
            app.ClearButton.Enable = true;
            app.SubmitButton.Enable = true;
        end
        
        function disable_draw(app)
        % DISABLE_DRAW(app)
        % 
        % Disables drawing on the Wacom tablet.
        
            % Move the mouse to the main experiment app
            app.MainApp.MouseObject.mouseMove( ...
                app.MainApp.UIFigure.Position(1)+(app.MainApp.UIFigure.Position(3)/2), ...
                app.MainApp.UIFigure.Position(2)+(app.MainApp.UIFigure.Position(4)/2) );
            
            % Disable drawing properties
            app.DrawEnable = false;
            app.ClearButton.Enable = false;
            app.SubmitButton.Enable = false;
        end
        
        function clear_wacom(app)
            ClearButtonPushed(app);
        end
        
        function submit_button_push(app)
            app.SubmitButtonPushed;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, amp_side)
            
            % Move figure to Wacom tablet
            monitor_number = 1; % monitor number of Wacom tablet (change this if needed)
            wacom_position = get(0, 'MonitorPositions');
            wacom_position = wacom_position(monitor_number,:);
            app.UIFigure.Position = [ ... % Set figure position (DO NOT TOUCH)
                wacom_position(1), wacom_position(2) + 32, ...
                wacom_position(3), wacom_position(4) - 64 ];
            
            
            % Load image into axis
            if strcmp(amp_side, 'right')
                img_file = 'foot_leg_image_right.jpg';
            else
                img_file = 'foot_leg_image_left.jpg';
            end
            app.Image = imread(img_file);
            app.Axes = axes(app.UIFigure);
            image(app.Axes, app.Image, 'ButtondownFcn', @app.pencil_start);
            axis(app.Axes, 'image');
            set(app.Axes, 'Toolbar', [], ...
                'XTickMode', 'manual', 'XTick', [], 'XTickLabel', {}, ...
                'YTickMode', 'manual', 'YTick', [], 'YTickLabel', {}, ...
                'XLimMode', 'manual', 'YLimMode', 'manual');
            
            % Initialize values for position of image and buttons in UIFigure (normalized units)
            img_norm_top_gap = 0.02; % gap size between top of UIFigure and image
            img_norm_height  = 0.8;  % height of image
            img_norm_width   = img_norm_height * ((app.UIFigure.Position(4) * size(app.Image,2)) / (app.UIFigure.Position(3)* size(app.Image,1)));
            bttn_norm_height = 0.6 * (1 - img_norm_height - img_norm_top_gap);
            bttn_norm_width  = 0.25 * img_norm_width;
            bttn_font_size   = round(0.6 * bttn_norm_height * app.UIFigure.Position(4) / 8) * 8;
            fig_size = [app.UIFigure.Position(3), app.UIFigure.Position(4), app.UIFigure.Position(3), app.UIFigure.Position(4)];
            
            % Set position of image and buttons (RATIO MATH! DO NOT TOUCH!)
            set(app.Axes, 'Units', 'normalized', 'Position', [ ...
                (1 - img_norm_width) / 2, ... 
                1 - img_norm_height - img_norm_top_gap, ...
                img_norm_width, ...
                img_norm_height ]);
            app.ClearButton.Position =  [ ...
                app.Axes.Position(1) + (((img_norm_width/2) - bttn_norm_width)/2), ...
                (app.Axes.Position(2) - bttn_norm_height)/2, ...
                bttn_norm_width, ...
                bttn_norm_height ] .* fig_size; % button units only in pixels
            app.ClearButton.FontSize = bttn_font_size;
            app.SubmitButton.Position = [ ...
                0.5 + (((img_norm_width/2) - bttn_norm_width)/2), ...
                (app.Axes.Position(2) - bttn_norm_height)/2, ...
                bttn_norm_width, ...
                bttn_norm_height ] .* fig_size; % button units only in pixels
            app.SubmitButton.FontSize = bttn_font_size;
            
            % Set main (calling) app
            app.MainApp = main_app;
            disable_draw(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if ~isempty(app.CurrLine)
                delete(app.CurrLine);
            end
            if ~isempty(app.DrawnLines)
                delete(app.DrawnLines);
            end
            app.CurrLine   = line([], []);
            app.DrawnLines = line([], []);
            app.SaveLines  = struct('index',{},'coord',{});
            app.NumLines   = 0;
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
            % Get file path
            if isempty(app.MainApp.SaveFilePath)
                app.MainApp.set_save_location;
            end
            file_path = strrep(app.MainApp.SaveFilePath, '.txt', '.mat');
            if exist(file_path, 'file')
                load(file_path, 'imagestruct');
            end
            
            % Save the data to imagestruct
            index = app.CurrIndex;
            imagestruct.(index).line = app.SaveLines;
            imagestruct.(index).data = app.MainApp.SaveData;
            save(file_path, 'imagestruct');
            
            % Clear lines
            ClearButtonPushed(app);
            app.MainApp.DrawingGUIButton.Value = false;
            disable_draw(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wacom_draw(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end